classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WedgesFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        WedgesFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ConstraintsFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        ConstraintsFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        StrategyFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        StrategyFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ReadFilesButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        GoGreenLabel                   matlab.ui.control.Label
        OutputStrategyButton           matlab.ui.control.Button
        Step1ReadFilesLabel            matlab.ui.control.Label
        Step2SelectAdditionalWedges1AddastartyearfordesiredwedgeLabel  matlab.ui.control.Label
        Step3OutputStrategyLabel       matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        WedgesLabel                    matlab.ui.control.Label
        ConstraintsLabel               matlab.ui.control.Label
        ClearAllButton                 matlab.ui.control.Button
        TotalNumberofWedgesUsedEditFieldLabel  matlab.ui.control.Label
        TotalNumberofWedgesUsedEditField  matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        TotalCostofWedgesEditFieldLabel  matlab.ui.control.Label
        TotalCostofWedgesEditField     matlab.ui.control.NumericEditField
        NumberofWedgestoReachDropDownLabel  matlab.ui.control.Label
        NumberofWedgestoReachDropDown  matlab.ui.control.DropDown
        GraphDropDownLabel             matlab.ui.control.Label
        GraphDropDown                  matlab.ui.control.DropDown
        ForMeanWarmingvsTimeGraphEditFieldLabel  matlab.ui.control.Label
        ForMeanWarmingvsTimeGraphEditField  matlab.ui.control.NumericEditField
        PleaseChooseaDifferentGraphtoimplementnewvalueLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        wedge_data;
        wedge_data1;
        wedge_data2;
        header;
        XData;
        XData1;
        XData2;
        YData;
        YData1;
        YData2;
        Constraints;
        Constraints1;
        Constraints2;
        Constraints_header;
        Strategies;
        Strategy_header;
        Count;
        Output;
        Carbon_emissions;
        Carbon_emissions1;
        Carbon_emissions2;
        Graph_legend;
        legend_wedges;
        Legend;
        Selected_Wedges;
        flatlineyear;
        flatliney;
        flatlinex;
        % Initialize all variables
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileID = fopen('historicaldata.txt','r');
            header1 = fgetl(fileID);
            % Extract header line from historical data
            
            app.Carbon_emissions = 10*2^((2068-2017)/50);
            app.Carbon_emissions1 = app.Carbon_emissions;
            % Set final carbon emissions value (for 2068)
            
            data = [];
            while ~feof(fileID)
                getdata = fgetl(fileID);
                u = str2num(getdata);
                data = [data; u];
            end
            % Extract historical data
            
            app.XData = data(:,1);
            app.XData = [app.XData; 2018; 2068];
            app.XData1 = app.XData;
            app.YData = data(:,2);
            app.YData = [app.YData; 10*2^((2018-2017)/50); app.Carbon_emissions];
            app.YData1 = app.YData;
            
            % Define historical data points
            % We assume linear emissions increase from 2018 to 2068 and beyond
            
            plot(app.UIAxes,app.XData,app.YData, '-b');
            app.UIAxes.XLim = [1959, 2068];
            
            % Plot historical data
            
            app.NumberofWedgestoReachDropDown.Items = {'flatline emissions', '1990 level emissions', '80% below 1990 level emissions'};
            app.GraphDropDown.Items = {'Carbon Emissions', 'CO2 Concentration vs. Time', 'Mean Warming vs. Time'};
           
            % Initialize Drop Down Items
            
        end

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
            value1 = app.WedgesFileNameWithoutQuotesEditField.Value;
            % Initialize variables
            
            hold(app.UIAxes, 'off');
            
            plot(app.UIAxes,app.XData,app.YData, '-b');
            app.UIAxes.XLim = [1959, 2068];
            
            hold(app.UIAxes, 'on');
            
            % Clear graph and plot historical data
            
            delete(legend(app.UIAxes));
            
            app.TotalNumberofWedgesUsedEditField.Value = 0;
            app.EditField.Value = 0;
            
            % Delete legend and initialize variables


            try
                if strfind(value1,'wedges')
                    FID = fopen(value1);
                    app.header = fgetl(FID);
                    % If file is correct, open text file and extract first line
                    
                    app.UITable.ColumnName = [strsplit(app.header, ';'), {'SelectedWedges'}, {'NumberUsed'}, {'StartYear'}];
                    % Make first line of file the header of the table
                    
                    app.wedge_data = {};
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ';');
                        app.wedge_data = [app.wedge_data ; x, {false}, {0}, {' '}];
                    end % end while
                    % Extract each line of the file


                    app.UITable.Data = {};
                    app.UITable.Data = app.wedge_data;
                    app.UITable.ColumnEditable = true;
                    app.wedge_data1 = app.wedge_data;
                    
                    % Display wedges data in the table 
                    
                    
                elseif isempty(value1)
                    warndlg('Please input a wedges file', 'Error');
                    return
                    % If the wedges area is empty, display error message
                    
                else
                    warndlg('Incorrect wedges file name entered. File name must include the word ''wedges''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Wedges file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display the error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value2 = app.ConstraintsFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables


            try
                if strfind(value2,'constraints')
                    % If the correct file is selected, extract each line
                   
                    FID = fopen(value2);
                    app.Constraints_header = fgetl(FID);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while
                    app.Constraints = data;
                    app.Constraints1 = data;
                    
                    % Extract Constraints Data and save it to global variables
                    
                    
                    app.UITable2.ColumnName = strsplit(app.Constraints_header, ' ');
                    app.UITable2.Data = app.Constraints;
                   
                    % Display the data in the Constraints table


                elseif isempty(value2)
                    warndlg('Please select a constraints file', 'Error');
                    return
                    % If the constraints field is empty, display error message
                    
                else
                    warndlg('Incorrect constraints file name entered. File name must include the word ''constraints''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                    
                end % end if
            catch
                warndlg('Constraints file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value3 = app.StrategyFileNameWithoutQuotesEditField.Value;
            data = {};
            app.Graph_legend = {};
            wedges = [];
            app.TotalCostofWedgesEditField.Value = 0;
            % Initialize variables
            
            try
                if strfind(value3,'strategy')
                    % If the correct file is selected, extract each line
                    
                    FID = fopen(value3);
                    app.Strategy_header = fgetl(FID);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while
                    
                    app.Strategies = data;
                    
                    % Extract Data and save it to a global variable
                    
                    [r, ~] = size(app.Strategies);
                    header1 = strsplit(app.header, ';');
                    [r2, ~] = size(app.Constraints);
                    
                    
                elseif isempty(value3)
                    return
                else
                    warndlg('Incorrect strategy file name entered. File name must include the word ''strategy''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Strategy file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display error message
            end % end try
            
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            [r2, ~] = size(app.Strategies);
            [r3, ~] = size(app.Constraints);
            header3 = strsplit(app.Strategy_header, ' ');
            [r4, ~] = size(string(app.UITable.ColumnName));
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            index7 = find(contains(header1, 'Category'));
            index8 = find(contains(header3, 'YearStart'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'StartYear'));
            index13 = find(contains(header1, 'Cost'));
            index14 = find(contains(header1, 'ID'));
            
            % Initialize indices
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes,app.XData,app.YData, '-b');
                app.UIAxes.XLim = [1959, 2068];
                
                hold(app.UIAxes, 'on');
                
            end % end if
            
            % If the Carbon Emissions graph is selected, clear the graph and plot historical data
           
                    
                    width = [];
                for d = 1:r4
                if d == index14
                    width = [width, 20];
                elseif d == index13
                    width = [width, 35];
                elseif d == index3
                    width = [width, 110];
                elseif d == index9
                    width = [width, 130];
                else
                    width = [width, {'auto'}];
                    
                end % end if
                end % end for
                
                app.UITable.ColumnWidth = width;
                
                % Redefine the width of the ID, StrategyName, Sector, and Cost columns


            app.Carbon_emissions = app.Carbon_emissions1;
            
            % Reset Carbon_emissions value to initial value
            
            for j = 1:r
                % Loop through each row of the Strategy Data
                
                IDnumber = str2num(app.Strategies{j, index6});
                % Extract the ID number from the Strategy File
                
                if app.wedge_data{IDnumber, index10} == false
                    app.wedge_data{IDnumber, index10} = true;
                    app.wedge_data{IDnumber, index11} = 1;
                    app.wedge_data{IDnumber, index12} = app.Strategies{j, index8};
                    
                    
                    app.UITable.Data = app.wedge_data;
                    
                    % If the check box is unchecked, check it, set the number used to 1, and update the starting year
                else
                    app.wedge_data{IDnumber, index11} = app.wedge_data{IDnumber, index11} + 1;
                    app.UITable.Data = app.wedge_data;
                    
                    % Otherwise, just add 1 to the number used
                    
                end % end if
               
                
                Constraint = strsplit(app.wedge_data{str2num(app.Strategies{j, index6}), index3}, ',');
                
                % Extract the Sector of the wedge from the wedge data
                
                for m = 1:r3
                    
                    % Loop through each Constraint from the Constraints file 
                    
                    for k = 1:numel(Constraint)
                        
                        % Loop through the sectors of the current wedge from the strategy data
                        
                        if strcmp(string(Constraint(k)), string(app.Constraints{m, index4}))
                            app.Constraints{m, index5} = num2str(str2num(app.Constraints{m, index5})-1);
                           
                            
                            app.UITable2.Data = app.Constraints;
                        end % end if
                        
                        % If the wedge sector matches the Constraint, subtract 1 from the Constraint value,
                        % then reprint the Constraint table with the new data
                        
                        if str2num(app.Constraints{m, index5}) < 0
                            warndlg('Too many files of desired sector. Please follow given constraints.', 'Error');
                            return
                        end % end if
                        
                        % If the constraint value is less than 0, display error message
                        
                    end % end for
                end % end for
                
                hold(app.UIAxes,'on');
                
                % Don't clear the graph
                
                m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
                b = (10*2^((2018-2017)/50)-m*2018);
                
                % Define m and b for the equation y = m*x + b going through 2018 and 2068
                % and their respective Carbon emissions values
                
                
                if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                    
                    % If the Carbon Emissions graph is selected
                    
                    xdata1 = [str2num(app.Strategies{j, index8}), 2068];
                    ydata1 = [m*str2num(data{j, index8})+b, app.Carbon_emissions];
                    plot(app.UIAxes,xdata1,ydata1);
                    
                    % Plot a line from the start year to 2068 using the emissions value
                    % of the start year from the y = m*x + b and the carbon emissions value
                    
                    
                    app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.Strategies{j, index8}))/50;
                    
                    app.XData2 = [str2num(app.Strategies{j, index8}), 2068];
                    app.YData2 = [m*str2num(app.Strategies{j, index8})+b, app.Carbon_emissions];
                    plot(app.UIAxes, app.XData2, app.YData2);
                    
                    app.Carbon_emissions2 = app.Carbon_emissions;
                    
                    hold(app.UIAxes,'on');
                    
                    Category = strsplit(app.wedge_data{str2num(data{j, index6}), index7}, ',');
                    if strcmp('Efficiency and Conservation', Category)
                        k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'y');
                    elseif strcmp('Fossil Fuel', Category)
                        k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'b');
                    elseif strcmp('Nuclear', Category)
                        k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'r');
                    elseif strcmp('Renewables and Biostorage', Category)
                        k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'g');
                    end % end if


                    if numel(app.Graph_legend) == 0
                        app.Graph_legend = {app.wedge_data{IDnumber, index9}};
                        app.legend_wedges = [k];
                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                    else
                        app.Graph_legend = [app.Graph_legend, app.wedge_data{IDnumber, index9}];
                        app.legend_wedges = [app.legend_wedges, k];
                    end % end if
                    delete(app.Legend)
                    app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                end % end if
                
                app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{IDnumber, index13});
                
            end % end for
            
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                app.flatlinex = [str2num(app.Strategies{j, index8}), 2068];
                app.flatliney = [m*str2num(data{j, index8})+b, m*str2num(data{j, index8})+b];
                plot(app.UIAxes, app.flatlinex, app.flatliney, 'k');
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                app.Constraints = app.Constraints2;
                
                app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);
                
                app.flatlineyear = str2num(app.Strategies{j, index8});
                
                plot(app.UIAxes, app.flatlinex, app.flatliney, 'k');
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                delete(app.Legend)
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
            end % end if
            
            app.flatliney = [m*str2num(data{j, index8})+b, m*str2num(data{j, index8})+b];
            app.flatlineyear = str2num(app.Strategies{j, index8});
            app.Carbon_emissions = app.Carbon_emissions1;
            
            if strcmp(app.NumberofWedgestoReachDropDown.Value, 'flatline emissions')
                app.Count = 0;
                while app.Carbon_emissions > app.flatliney(1)
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                end % end while
                
                app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
            elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                end % end while
            elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '80% below 1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06*0.2
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                end % end while
            end % end if
            
            cydata = [800];
            
            if strcmp(app.GraphDropDown.Value, 'CO2 Concentration vs. Time')
                
                start = (app.XData == 2000);
                startyear = find(start);
                
                for j = 2:length(app.XData(startyear:end))
                    cydata(j) = cydata(j-1)+1*0.5*(10*2^((app.XData(j + startyear-1)-2017)/50));
                    
                end % end for
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes, app.XData(startyear:end), cydata);
                ylabel(app.UIAxes, 'CO_2 Concentration (GtC)');
                title(app.UIAxes, 'CO_2 Concentration vs. Time');
                
                hold(app.UIAxes, 'on');
                
            end % end if
            
            app.EditField.Value = app.Count;
            
            app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);


        end

        % Button pushed function: OutputStrategyButton
        function OutputStrategyButtonPushed(app, event)
            
            
            header1 = app.UITable.ColumnName;
            fid = fopen('output.txt', 'wt');
            headerline = ['ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges'];
            fprintf(fid, headerline);
            app.Output = [];
            line = {};
            [r1, ~] = size(app.wedge_data);
            index1 = find(contains(header1, 'ID'));
            index2 = find(contains(header1, 'StrategyName'));
            index3 = find(contains(header1, 'Sector'));
            index5 = find(contains(header1, 'Cost'));
            index6 = find(contains(header1, 'Challenges'));
            index7 = find(contains(header1, 'SelectedWedges'));
            index8 = find(contains(header1, 'NumberUsed'));


            for j = 1:r1
                
                if app.wedge_data{j, index8} > sum([app.wedge_data{:, index8}])/2 && app.wedge_data{j, index8} > 1
                    warndlg('One wedge cannot account for more than half of the selected wedges', 'Error');
                    return
                end % end if
                
                if app.wedge_data{j, index7} == true
                    line = [app.wedge_data{j, index1}, ';', app.wedge_data{j, index2}, ';', app.wedge_data{j, index3}, ';', num2str(app.wedge_data{j, index8}), ';', num2str(str2num(app.wedge_data{j, index5})*app.wedge_data{j, index8}), ';', app.wedge_data{j, index6}];
                    fprintf(fid, '\n%s', line);
                end % end if
            end % end for


            fclose(fid);


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            [r1, c1] = size(app.wedge_data);
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            header7 = app.UITable2.ColumnName;
            app.UITable2.Data = app.Constraints1;
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index7 = find(contains(header1, 'Category'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'Cost'));
            index13 = find(contains(header1, 'StartYear'));
            index14 = find(contains(header1, 'ID'));
            
            app.wedge_data = app.UITable.Data;
            app.Constraints = app.Constraints1;
            [r2, ~] = size(app.Constraints);
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes,app.XData,app.YData, '-b');
                app.UIAxes.XLim = [1959, 2068];
                
                hold(app.UIAxes, 'on');
            end % end if
            
            app.Carbon_emissions = app.Carbon_emissions1;
            app.Graph_legend = {};
            app.legend_wedges = [];
            
            app.TotalCostofWedgesEditField.Value = 0;
            
            for i = 1:r1-1
                for q = i+1:r1
                    if (strcmp(app.wedge_data{i, index13}, ' ') && app.wedge_data{i, index10} == true) || (strcmp(app.wedge_data{q, index13}, ' ') && app.wedge_data{q, index10} == true)
                        warndlg('Please enter a starting year');
                        return
                    end % end if
                    if (app.wedge_data{i, index10} == true) && (app.wedge_data{q, index10} == true) && (str2num(app.wedge_data{i, index13}) < str2num(app.wedge_data{q, index13}))
                        for g = 1:c1
                            position = app.wedge_data{i,g};
                            app.wedge_data{i,g} = app.wedge_data{q,g};
                            app.wedge_data{q,g} = position;
                        end % end for
                    end % end if
                end % end for
            end % end for
            
            app.UITable.Data = app.wedge_data;
            
            
            for j = 1:r1
                
                
                if app.UITable.Data{j, index10} == true && app.UITable.Data{j, index11} == 0
                    app.wedge_data{j, index11} = 1;
                    app.wedge_data{j, index10} = true;
                    app.UITable.Data = app.wedge_data;
                elseif app.UITable.Data{j, index10} == false && app.UITable.Data{j, index11} ~= 0
                    app.wedge_data{j, index11} = 0;
                    app.wedge_data{j, index10} = false;
                    app.UITable.Data = app.wedge_data;
                end % end if
                
                if app.wedge_data{j, index10} == true
                    Constraint = strsplit(app.wedge_data{j, index3}, ',');
                    for k = 1:numel(Constraint)
                        for h = 1:r2
                            if strcmp(string(Constraint(k)), string(app.Constraints{h, index4}))
                                app.Constraints{h, index5} = num2str(str2num(app.Constraints{h, index5}) - app.wedge_data{j, index11});
                                app.UITable2.Data = app.Constraints;
                            end % end if
                            if str2num(app.Constraints{h, index5}) < 0
                                warndlg('Too many files of desired sector. Please follow given constraints. (Please undo last action)', 'Error');
                                return
                            end % end if
                        end % end for
                    end % end for
                end % end if
                
                
                hold(app.UIAxes, 'on');
                m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
                b = (10*2^((2018-2017)/50)-m*2018);
                year = str2num(app.wedge_data{j, index13});
                
                app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{j, index12})*app.wedge_data{j, index11};
                
                if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                    
                    delete(legend(app.UIAxes));


                    if app.wedge_data{j, index10} == true
                        if str2num(app.wedge_data{j, index13}) > 2067 || str2num(app.wedge_data{j, index13}) < 2018
                            warndlg('Please choose a starting year between 2018 and 2067.', 'Error');
                            return
                        else
                            for c = 1:app.wedge_data{j, index11}
                                xdata1 = [year, 2068];
                                ydata1 = [m*year+b, app.Carbon_emissions];
                                plot(app.UIAxes,xdata1,ydata1, '-');
                                
                                app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.wedge_data{j, index13}))/50;
                                
                                app.XData2 = [year, 2068];
                                app.YData2 = [m*year+b, app.Carbon_emissions];
                                plot(app.UIAxes, app.XData2, app.YData2, '-');
                                %
                                app.Carbon_emissions2 = app.Carbon_emissions;
                                
                                Category = app.wedge_data{j, index7};
                                k = [];
                                
                                if strcmp('Efficiency and Conservation', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'y');
                                elseif strcmp('Fossil Fuel', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'b');
                                elseif strcmp('Nuclear', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'r');
                                elseif strcmp('Renewables and Biostorage', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'g');
                                end % end if
                                
                                if numel(app.Graph_legend) == 0
                                    app.Graph_legend = {app.wedge_data{j, index9}};
                                    app.legend_wedges = [k];
                                    app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                                else
                                    app.Graph_legend = [app.Graph_legend, app.wedge_data{j, index9}];
                                    app.legend_wedges = [app.legend_wedges, k];
                                end % end if
                            end % end if
                            
                        end % end for
                        app.flatlinex = [str2num(app.wedge_data{j, index13}), 2068];
                        app.flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
                        
                        app.flatlineyear = str2num(app.wedge_data{j, index13});
                        
                        
                    end % end if
                end % end if
            end % end for
            
            
            for b = 1:r1
                if app.wedge_data{b, index10} == false
                else
                    if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                        app.Carbon_emissions = app.Carbon_emissions1;
                        
                        plot(app.UIAxes, app.flatlinex, app.flatliney, 'k');
                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                        
                        delete(app.Legend)
                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                    end % end if
                    
                    app.Count = 0;
                    if strcmp(app.NumberofWedgestoReachDropDown.Value, 'flatline emissions')
                        app.Count = 0;
                        while app.Carbon_emissions > app.flatliney(1)
                            app.Count = app.Count + 1;
                            app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                        end % end while
                        
                        app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
                    elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '1990 level emissions')
                        app.Count = 0;
                        while app.Carbon_emissions > 6.06
                            app.Count = app.Count + 1;
                            app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                        end % end while
                    elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '80% below 1990 level emissions')
                        app.Count = 0;
                        while app.Carbon_emissions > 6.06*0.2
                            app.Count = app.Count + 1;
                            app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                        end % end while
                    end % end if
                    
                    app.EditField.Value = app.Count;
                    
                    app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);
                end % end if
            end % end for
            
            for i = 1:r1
                for q = i+1:r1
                    if (str2num(app.wedge_data{i, index14}) > str2num(app.wedge_data{q, index14}))
                        for g = 1:c1
                            position = app.wedge_data{i,g};
                            app.wedge_data{i,g} = app.wedge_data{q,g};
                            app.wedge_data{q,g} = position;
                        end % end for
                    end % end if
                end % end for
            end % end for
            
            app.UITable.Data = app.wedge_data;


        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.Constraints = app.Constraints1;
            app.UITable2.Data = app.Constraints;


            hold(app.UIAxes, 'off');
            
            plot(app.UIAxes,app.XData,app.YData, '-b');
            app.UIAxes.XLim = [1959, 2068];
            delete(legend(app.UIAxes));
            
            hold(app.UIAxes, 'on');
            
            app.wedge_data = app.wedge_data1;
            app.UITable.Data = app.wedge_data;
            app.TotalNumberofWedgesUsedEditField.Value = 0;
            app.TotalCostofWedgesEditField.Value = 0;
            app.EditField.Value = 0;
            
        end

        % Value changed function: NumberofWedgestoReachDropDown
        function NumberofWedgestoReachDropDownValueChanged(app, event)
            if isempty(app.wedge_data)
                warndlg('Please read files first.', 'Error');
                return
            end % end if
            
            value = app.NumberofWedgestoReachDropDown.Value;
            [r1, ~] = size(app.wedge_data);
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            header5 = strsplit(app.Strategy_header, ' ');
            header7 = app.UITable2.ColumnName;
            header3 = strsplit(app.Strategy_header, ' ');
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            index7 = find(contains(header1, 'Category'));
            index8 = find(contains(header5, 'YearStart'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'Cost'));
            index13 = find(contains(header1, 'StartYear'));
            
            m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
            b = (10*2^((2018-2017)/50)-m*2018);
            
            for j = 1:r1
                
                if app.wedge_data{j, index10} == true
                    
                    app.flatlineyear = str2num(app.wedge_data{j, index13});
                    app.flatliney = m*str2num(app.wedge_data{j, index13})+b;
                    
                end % end if
                
            end % end for
            
            app.Carbon_emissions = app.Carbon_emissions1;
            
            if strcmp(value, 'flatline emissions')
                app.Count = 0;
                while app.Carbon_emissions > app.flatliney
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                end % end while
                
                app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
            elseif strcmp(value, '1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                end % end while
            elseif strcmp(value, '80% below 1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06*0.2
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                end % end while
            end % end if
            
            app.EditField.Value = app.Count;
            
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            
            if isempty(app.wedge_data)
                app.GraphDropDown.Value = 'Carbon Emissions';
                warndlg('Please read files first.', 'Error');
                return
            end % end if
            
            m = (app.YData2(2)-app.YData2(1))/(app.XData2(2) - app.XData2(1));
            b = app.YData2(1)-m*app.XData2(1);
            
            [r1, c1] = size(app.wedge_data);
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            header7 = app.UITable2.ColumnName;
            app.UITable2.Data = app.Constraints1;
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index7 = find(contains(header1, 'Category'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'Cost'));
            index13 = find(contains(header1, 'StartYear'));
            index14 = find(contains(header1, 'ID'));
            
            app.wedge_data = app.UITable.Data;
            app.Constraints = app.Constraints1;
            [r2, ~] = size(app.Constraints);


            cydata = [800];
            
            
            start = (app.XData == 2017);
            startyear = find(start);
            Number = 0;
            
            start = (app.XData == 2000);
            startyear1 = find(start);
            
            for j = 2018:2068
                Number = Number + 1;
                app.XData1(startyear+Number) = j;
                
                if j < app.flatlinex(1)
                    app.YData1(startyear+Number) = 10*2^((j-2017)/50);
                elseif j == app.flatlinex(1)
                    app.YData1(startyear+Number) = app.flatliney(1);
                elseif j > app.flatlinex(1)
                    app.YData1(startyear+Number) = m*app.XData1(startyear+Number)+b;
                end % end if
            end % end for
            
            
            for j = 2:length(app.YData1(startyear1:end))
                cydata(j) = cydata(j-1)+1*0.5*app.YData1(j + startyear1-1);
            end % end for
            
            cydata = cydata./2.1;
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
            if  strcmp(value, 'Carbon Emissions')
                
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes,app.XData,app.YData, '-b');
                app.UIAxes.XLim = [1959, 2068];
                
                hold(app.UIAxes, 'on');
            end % end if
            
            app.Carbon_emissions = app.Carbon_emissions1;
            app.Graph_legend = {};
            app.legend_wedges = [];
            
            app.TotalCostofWedgesEditField.Value = 0;
            
            for i = 1:r1-1
                for q = i+1:r1
                    if (strcmp(app.wedge_data{i, index13}, ' ') && app.wedge_data{i, index10} == true) || (strcmp(app.wedge_data{q, index13}, ' ') && app.wedge_data{q, index10} == true)
                        warndlg('Please enter a starting year');
                        return
                    end % end if
                    if (app.wedge_data{i, index10} == true) && (app.wedge_data{q, index10} == true) && (str2num(app.wedge_data{i, index13}) < str2num(app.wedge_data{q, index13}))
                        for g = 1:c1
                            position = app.wedge_data{i,g};
                            app.wedge_data{i,g} = app.wedge_data{q,g};
                            app.wedge_data{q,g} = position;
                        end % end for
                    end % end if
                end % end for
            end % end for
            
            app.UITable.Data = app.wedge_data;
            
            
            for j = 1:r1
                
                
                if app.UITable.Data{j, index10} == true && app.UITable.Data{j, index11} == 0
                    app.wedge_data{j, index11} = 1;
                    app.wedge_data{j, index10} = true;
                    app.UITable.Data = app.wedge_data;
                elseif app.UITable.Data{j, index10} == false && app.UITable.Data{j, index11} ~= 0
                    app.wedge_data{j, index11} = 0;
                    app.wedge_data{j, index10} = false;
                    app.UITable.Data = app.wedge_data;
                end % end if
                
                if app.wedge_data{j, index10} == true
                    Constraint = strsplit(app.wedge_data{j, index3}, ',');
                    for k = 1:numel(Constraint)
                        for h = 1:r2
                            if strcmp(string(Constraint(k)), string(app.Constraints{h, index4}))
                                app.Constraints{h, index5} = num2str(str2num(app.Constraints{h, index5}) - app.wedge_data{j, index11});
                                app.UITable2.Data = app.Constraints;
                            end % end if
                            if str2num(app.Constraints{h, index5}) < 0
                                warndlg('Too many files of desired sector. Please follow given constraints. (Please undo last action)', 'Error');
                                return
                            end % end if
                        end % end for
                    end % end for
                end % end if


                hold(app.UIAxes, 'on');
                m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
                b = (10*2^((2018-2017)/50)-m*2018);
                year = str2num(app.wedge_data{j, index13});
                
                app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{j, index12})*app.wedge_data{j, index11};
                
                if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                    
                    delete(legend(app.UIAxes));


                    if app.wedge_data{j, index10} == true
                        if str2num(app.wedge_data{j, index13}) > 2067 || str2num(app.wedge_data{j, index13}) < 2018
                            warndlg('Please choose a starting year between 2018 and 2067.', 'Error');
                            return
                        else
                            for c = 1:app.wedge_data{j, index11}
                                xdata1 = [year, 2068];
                                ydata1 = [m*year+b, app.Carbon_emissions];
                                plot(app.UIAxes,xdata1,ydata1, '-');
                                
                                app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.wedge_data{j, index13}))/50;
                                
                                app.XData2 = [year, 2068];
                                app.YData2 = [m*year+b, app.Carbon_emissions];
                                plot(app.UIAxes, app.XData2, app.YData2, '-');
                                %
                                app.Carbon_emissions2 = app.Carbon_emissions;
                                
                                Category = app.wedge_data{j, index7};
                                k = [];
                                
                                if strcmp('Efficiency and Conservation', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'y');
                                elseif strcmp('Fossil Fuel', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'b');
                                elseif strcmp('Nuclear', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'r');
                                elseif strcmp('Renewables and Biostorage', Category)
                                    k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'g');
                                end % end if
                                
                                if numel(app.Graph_legend) == 0
                                    app.Graph_legend = {app.wedge_data{j, index9}};
                                    app.legend_wedges = [k];
                                    app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                                else
                                    app.Graph_legend = [app.Graph_legend, app.wedge_data{j, index9}];
                                    app.legend_wedges = [app.legend_wedges, k];
                                end % end if
                            end % end if
                            
                        end % end for
                        app.flatlinex = [str2num(app.wedge_data{j, index13}), 2068];
                        app.flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
                        
                        app.flatlineyear = str2num(app.wedge_data{j, index13});
                        
                        
                    end % end if
                end % end if
            end % end for
            
            
            for b = 1:r1
                if app.wedge_data{b, index10} == false
                else
                    if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                        app.Carbon_emissions = app.Carbon_emissions1;
                        
                        plot(app.UIAxes, app.flatlinex, app.flatliney, 'k');
                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                        
                        delete(app.Legend)
                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                    end % end if
                    
                    app.Count = 0;
                    if strcmp(app.NumberofWedgestoReachDropDown.Value, 'flatline emissions')
                        app.Count = 0;
                        while app.Carbon_emissions > app.flatliney(1)
                            app.Count = app.Count + 1;
                            app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                        end % end while
                        
                        app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
                    elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '1990 level emissions')
                        app.Count = 0;
                        while app.Carbon_emissions > 6.06
                            app.Count = app.Count + 1;
                            app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                        end % end while
                    elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '80% below 1990 level emissions')
                        app.Count = 0;
                        while app.Carbon_emissions > 6.06*0.2
                            app.Count = app.Count + 1;
                            app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
                        end % end while
                    end % end if
                    
                    app.EditField.Value = app.Count;
                    
                    app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);
                end % end if
            end % end for
            
            for i = 1:r1
                for q = i+1:r1
                    if (str2num(app.wedge_data{i, index14}) > str2num(app.wedge_data{q, index14}))
                        for g = 1:c1
                            position = app.wedge_data{i,g};
                            app.wedge_data{i,g} = app.wedge_data{q,g};
                            app.wedge_data{q,g} = position;
                        end % end for
                    end % end if
                end % end for
            end % end for
            
            app.UITable.Data = app.wedge_data;
            
            %                 [r1, ~] = size(app.wedge_data);
            %
            %                 header1 = app.UITable.ColumnName;
            %                 header4 = app.UITable2.ColumnName;
            %                 header5 = strsplit(app.Strategy_header, ' ');
            %                 header7 = app.UITable2.ColumnName;
            %                 app.UITable2.Data = app.Constraints1;
            %                 header3 = strsplit(app.Strategy_header, ' ');
            %
            %                 index1 = find(contains(header1, 'Strategy'));
            %                 index3 = find(contains(header1, 'Sector'));
            %                 index4 = find(contains(header4, 'Sector'));
            %                 index5 = find(contains(header4, 'Constraints'));
            %                 index6 = find(contains(header3, 'ID'));
            %                 index7 = find(contains(header1, 'Category'));
            %                 index8 = find(contains(header5, 'YearStart'));
            %                 index9 = find(contains(header1, 'StrategyName'));
            %                 index10 = find(contains(header1, 'SelectedWedges'));
            %                 index11 = find(contains(header1, 'NumberUsed'));
            %                 index12 = find(contains(header1, 'Cost'));
            %                 index13 = find(contains(header1, 'StartYear'));
            %                 app.wedge_data = app.UITable.Data;
            %                 app.Constraints = app.Constraints1;
            %                 [r2, ~] = size(app.Constraints);
            %
            %                 hold(app.UIAxes, 'off');
            %
            %                 app.XData = app.XData1;
            %                 app.YData = app.YData1;
            %
            %                 plot(app.UIAxes,app.XData,app.YData, '-b');
            %                 app.UIAxes.XLim = [1959, 2068];
            %
            %                 hold(app.UIAxes, 'on');
            %                 delete(legend(app.UIAxes));
            %
            %                 app.Carbon_emissions = app.Carbon_emissions1;
            %                 app.Graph_legend = {};
            %                 app.legend_wedges = [];
            %
            %                 app.TotalCostofWedgesEditField.Value = 0;
            %
            %                 delete(legend(app.UIAxes));
            %
            %
            %                 for j = 1:r1
            %
            %                     if app.UITable.Data{j, index10} == true && app.UITable.Data{j, index11} == 0
            %                         app.wedge_data{j, index11} = 1;
            %                         app.wedge_data{j, index10} = true;
            %                         app.UITable.Data = app.wedge_data;
            %                     elseif app.UITable.Data{j, index10} == false && app.UITable.Data{j, index11} ~= 0
            %                         app.wedge_data{j, index11} = 0;
            %                         app.wedge_data{j, index10} = false;
            %                         app.UITable.Data = app.wedge_data;
            %                     end % end if
            %
            %                     if app.wedge_data{j, index10} == true
            %                         Constraint = strsplit(app.wedge_data{j, index3}, ',');
            %                         for k = 1:numel(Constraint)
            %                             for h = 1:r2
            %                                 if strcmp(string(Constraint(k)), string(app.Constraints{h, index4}))
            %                                     app.Constraints{h, index5} = num2str(str2num(app.Constraints{h, index5}) - app.wedge_data{j, index11});
            %                                     app.UITable2.Data = app.Constraints;
            %                                 end % end if
            %                                 if str2num(app.Constraints{h, index5}) < 0
            %                                     warndlg('Too many files of desired sector. Please follow given constraints. (Please undo last action)', 'Error');
            %                                     return
            %                                 end % end if
            %                             end % end for
            %                         end % end for
            %                     end % end if
            %
            %
            %                     hold(app.UIAxes, 'on');
            %                     year = str2num(app.wedge_data{j, index13});
            %
            %                     if isempty(year) && app.wedge_data{j, index11} > 0
            %                         warndlg('Please enter a starting year');
            %                         return
            %                     end % end if
            %
            %                     if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
            %                         if app.wedge_data{j, index10} == true
            %                             if str2num(app.wedge_data{j, index13}) > 2067
            %                                 warndlg('Please choose a starting year between 2018 and 2067.', 'Error');
            %                                 return
            %                             else
            %                                 for c = 1:app.wedge_data{j, index11}
            %                                     xdata1 = [year, 2068];
            %                                     ydata1 = [m*year+b, app.Carbon_emissions];
            %                                     plot(app.UIAxes,xdata1,ydata1, '-');
            %                                     %
            %                                     %
            %
            %                                     app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.wedge_data{j, index13}))/50;
            %
            %                                     app.XData2 = [year, 2068];
            %                                     app.YData2 = [m*year+b, app.Carbon_emissions];
            %                                     plot(app.UIAxes, app.XData2, app.YData2, '-');
            %                                     %
            %                                     Category = app.wedge_data{j, index7};
            %                                     k = [];
            %
            %                                     if strcmp('Efficiency and Conservation', Category)
            %                                         k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'y');
            %                                     elseif strcmp('Fossil Fuel', Category)
            %                                         k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'b');
            %                                     elseif strcmp('Nuclear', Category)
            %                                         k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'r');
            %                                     elseif strcmp('Renewables and Biostorage', Category)
            %                                         k = fill(app.UIAxes, [xdata1, app.XData2(2)], [ydata1, app.YData2(2)], 'g');
            %                                     end % end if
            %
            %                                     if numel(app.Graph_legend) == 0
            %                                         app.Graph_legend = {app.wedge_data{j, index9}};
            %                                         app.legend_wedges = [k];
            %                                         app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
            %                                     else
            %                                         app.Graph_legend = [app.Graph_legend, app.wedge_data{j, index9}];
            %                                         app.legend_wedges = [app.legend_wedges, k];
            %                                     end % end if
            %                                 end % end if
            
            %                             end % end for
            %                             app.flatlinex = [str2num(app.wedge_data{j, index13}), 2068];
            %                             app.flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
            %
            %                             app.flatlineyear = str2num(app.wedge_data{j, index13});
            %
            %                             app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{j, index12})*app.wedge_data{j, index11};
            %                         elseif strcmp(app.GraphDropDown.Value, 'CO2 Concentration vs. Time')
            %                             if app.wedge_data{j, index10} == true
            %                                 app.flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
            %
            %                                 app.flatlineyear = str2num(app.wedge_data{j, index13});
            %                             end % end if
            %
            %
            %                         end % end if
            %                     end % end if
            %                 end % end for
            %
            %                 app.Carbon_emissions = app.Carbon_emissions1;
            %
            %
            %                 for z = 1:r1
            %                     if app.wedge_data{z, index10} == true
            %
            %                         plot(app.UIAxes, app.flatlinex, app.flatliney, 'k');
            %                         app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
            %
            %                         delete(app.Legend)
            %                         app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
            %                     end % end if
            %                 end % end for
            %
            %                 app.Count = 0;
            %                 if strcmp(app.NumberofWedgestoReachDropDown.Value, 'flatline emissions')
            %                     app.Count = 0;
            %                     while app.Carbon_emissions > app.flatliney(1)
            %                         app.Count = app.Count + 1;
            %                         app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
            %                     end % end while
            %
            %                     app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
            %                 elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '1990 level emissions')
            %                     app.Count = 0;
            %                     while app.Carbon_emissions > 6.06
            %                         app.Count = app.Count + 1;
            %                         app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
            %                     end % end while
            %                 elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '80% below 1990 level emissions')
            %                     app.Count = 0;
            %                     while app.Carbon_emissions > 6.06*0.2
            %                         app.Count = app.Count + 1;
            %                         app.Carbon_emissions = app.Carbon_emissions - (2068-app.flatlineyear)/50;
            %                     end % end while
            %                 end % end if
            %
            %                 app.EditField.Value = app.Count;
            %
            %                 app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);
            %
            %                 title(app.UIAxes, 'Carbon Emissions Over Time');
            %                 ylabel(app.UIAxes, 'Carbon Emissions (Gt/yr)');


            elseif strcmp(value, 'CO2 Concentration vs. Time')
                
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes, app.XData1(startyear1:end), cydata);
                ylabel(app.UIAxes, 'CO_2 Concentration (ppm)');
                title(app.UIAxes, 'CO_2 Concentration vs. Time');
                delete(legend(app.UIAxes));
                
                hold(app.UIAxes, 'on');
                
                app.UIAxes.XLim = [2000, 2068];
                
                
                elseif strcmp(value, 'Mean Warming vs. Time')
                    
                    
                    tdatay = app.ForMeanWarmingvsTimeGraphEditField.Value .* (5.35 .* log(cydata./278));
                    
                    hold(app.UIAxes, 'off');
                    plot(app.UIAxes, app.XData1(startyear1:end), tdatay);
                    
                    title(app.UIAxes, 'Mean Warming vs. Time');
                    ylabel(app.UIAxes, '\Delta T (^{o}C)');
                    
                    delete(legend(app.UIAxes));
                    
                    app.UIAxes.XLim = [2000, 2068];
                    
        end % end if
        
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesFileNameWithoutQuotesEditFieldLabel.Text = 'Wedges File Name (Without Quotes)';
            app.WedgesFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesFileNameWithoutQuotesEditField.Value = 'wedges.txt';
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel.Text = 'Constraints File Name (Without Quotes)';
            app.ConstraintsFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsFileNameWithoutQuotesEditField.Value = 'constraints.txt';
            app.StrategyFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyFileNameWithoutQuotesEditFieldLabel.Text = 'Strategy File Name (Without Quotes)';
            app.StrategyFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyFileNameWithoutQuotesEditField.Value = 'strategy.txt';
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Text = 'Read Files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carbon Emissions over Time')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Carbon Emissions (Gt/yr)')
            app.GoGreenLabel = uilabel(app.UIFigure);
            app.GoGreenLabel.Text = 'Go Green!';
            app.OutputStrategyButton = uibutton(app.UIFigure, 'push');
            app.OutputStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @OutputStrategyButtonPushed, true);
            app.OutputStrategyButton.Text = 'Output Strategy';
            app.Step1ReadFilesLabel = uilabel(app.UIFigure);
            app.Step1ReadFilesLabel.Text = 'Step 1: Read Files';
            app.Step2SelectAdditionalWedges1AddastartyearfordesiredwedgeLabel = uilabel(app.UIFigure);
            app.Step2SelectAdditionalWedges1AddastartyearfordesiredwedgeLabel.Text = {'Step 2: Select Additional Wedges'; ' 1) Add a start year for desired wedge'; ' 2) Check box in SelectedWedges column'; ' 3) Edit number of wedges used if desired'};
            app.Step3OutputStrategyLabel = uilabel(app.UIFigure);
            app.Step3OutputStrategyLabel.Text = 'Step 3: Output Strategy';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.WedgesLabel = uilabel(app.UIFigure);
            app.WedgesLabel.Text = 'Wedges';
            app.ConstraintsLabel = uilabel(app.UIFigure);
            app.ConstraintsLabel.Text = 'Constraints';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.TotalNumberofWedgesUsedEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNumberofWedgesUsedEditFieldLabel.Text = 'Total Number of Wedges Used';
            app.TotalNumberofWedgesUsedEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCostofWedgesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCostofWedgesEditFieldLabel.Text = 'Total Cost of Wedges';
            app.TotalCostofWedgesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofWedgestoReachDropDownLabel = uilabel(app.UIFigure);
            app.NumberofWedgestoReachDropDownLabel.Text = 'Number of Wedges to Reach';
            app.NumberofWedgestoReachDropDown = uidropdown(app.UIFigure);
            app.NumberofWedgestoReachDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofWedgestoReachDropDownValueChanged, true);
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.ForMeanWarmingvsTimeGraphEditFieldLabel = uilabel(app.UIFigure);
            app.ForMeanWarmingvsTimeGraphEditFieldLabel.Text = 'λ (For Mean Warming vs. Time Graph';
            app.ForMeanWarmingvsTimeGraphEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForMeanWarmingvsTimeGraphEditField.Value = 0.7;
            app.PleaseChooseaDifferentGraphtoimplementnewvalueLabel = uilabel(app.UIFigure);
            app.PleaseChooseaDifferentGraphtoimplementnewvalueLabel.Text = 'Please Choose a Different Graph to implement new value';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end