classdef dumbplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
    end


    properties (Access = private)
        XData % Description
        YData % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileID = fopen('historicaldata.txt','r');
            header = fgetl(fileID);
            
            data = [];
            while ~feof(fileID)
                getdata = fgetl(fileID);
                u = str2num(getdata);
                data = [data; u];
            end
            
            app.XData = data(:,1);
            app.YData = data(:,2);
            plot(app.UIAxes,app.XData,app.YData);
            app.UIAxes.XLim = [1959, 2017];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)

            value = app.Slider.Value;
            app.UIAxes.XLim = [value,2017];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1959 2017];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1959;
        end
    end

    methods (Access = public)

        % Construct app
        function app = dumbplot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end